classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        CountryListBoxLabel       matlab.ui.control.Label
        CountryListBox            matlab.ui.control.ListBox
        StateListBoxLabel         matlab.ui.control.Label
        StateListBox              matlab.ui.control.ListBox
        AverageofdaysSliderLabel  matlab.ui.control.Label
        AverageofdaysSlider       matlab.ui.control.Slider
        TypeofdataButtonGroup     matlab.ui.container.ButtonGroup
        CasesButton               matlab.ui.control.RadioButton
        DeathButton               matlab.ui.control.RadioButton
        BothButton                matlab.ui.control.RadioButton
        OptionButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton          matlab.ui.control.RadioButton
        DailyButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        xData
        Cases
        Cases_index
        Deaths_index
        AverageCases
        AverageDeaths
        AveragingWindow
        Int_title
        tav
    end
    methods (Access= private)
        function PlotData(app)
           app.UIAxes.reset;
           c1=int8('Cumulative Number of Cases in ');
           c2=int8('Cumulative Number of Deaths in ');
           c3=int8('Cumulative Number of Cases and Deaths in ');
           d1=int8('Daily Number of Cases in ');
           d2=int8('Daily Number of Deaths in ');
           d3=int8('Daily Number of Cases and Deaths in ');
            if app.CasesButton.Value && app.CumulativeButton.Value
                app.Cumulative;
                app.ComputeAverage;
                bar(app.UIAxes,app.XData,app.AverageCases,'b');
                num_title=[c1 app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.DeathButton.Value && app.CumulativeButton.Value 
                app.Cumulative;
                app.ComputeAverage;
                plot(app.UIAxes,app.XData,app.AverageDeaths,'red');
                num_title=[c2 app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.CasesButton.Value && app.DailyButton.Value
                app.Daily;
                app.ComputeAverage;
                bar(app.UIAxes,app.XData,app.AverageCases,'b');
                num_title=[d1 app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.DeathButton.Value && app.DailyButton.Value
                app.Daily;
                app.ComputeAverage;
                plot(app.UIAxes,app.XData,app.AverageDeaths,'red');
                num_title=[d2 app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.BothButton.Value && app.CumulativeButton.Value
                app.Cumulative;
                app.ComputeAverage;
                yyaxis(app.UIAxes,'left');    % Command for plotting graph with two y axis
                bar(app.UIAxes,app.XData,app.AverageCases,'b');
                app.UIAxes.YColor=[0 0 1];
                app.UIAxes.YLimMode='auto'; app.UIAxes.YTickMode='auto';
                set(app.UIAxes,'YTickLabel',num2str(get(app.UIAxes,'YTick').',     '%.f'));
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.xData,app.AverageDeaths,'red');
                app.UIAxes.YColor=[1 0 0];
                num_title=[c3 app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.BothButton.Value && app.DailyButton.Value
                app.Daily;
                app.ComputeAverage;
                yyaxis(app.UIAxes,'left');    % Command for plotting graph with two y axis
                bar(app.UIAxes,app.XData,app.AverageCases,'b');
                app.UIAxes.YColor=[0.00 0.00 1.00];
                app.UIAxes.YLimMode='auto'; app.UIAxes.YTickMode='auto';
                set(app.UIAxes,'YTickLabel',num2str(get(app.UIAxes,'YTick').',     '%.f'));
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.xData,app.AverageDeaths,'red');
                app.UIAxes.YColor=[1 0 0];
                num_title=[d3 app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            end
            xlim(app.UIAxes,[app.xData(1),app.xData(end)]);
            xlim(app.UIAxes,'manual');
            app.UIAxes.YLimMode='auto';
            app.UIAxes.YTickMode='auto';
            grid(app.UIAxes,'on');
            datetick(app.UIAxes,'x','mm/dd',"keeplimits");
            set(app.UIAxes,'YTickLabel', num2str(get(app.UIAxes,'YTick').',     '%.f'));
            
        end
        function ComputeAverage(app)
            app.AverageCases=movmean(app.Cases_index,app.AveragingWindow);
            app.AverageDeaths=movmean(app.Deaths_index,app.AveragingWindow);
            
        end
        function Daily(app)
            app.Cumulative;
            x=app.Cases_index-circshift(app.Cases_index,1);
            x(1)=app.Cases_index(1);
            k=logical(x<0);
            x(k)=0;
            app.Cases_index=x;
            y=app.Deaths_index-circshift(app.Deaths_index,1);
            y(1)=app.Deaths_index(1);
            g=logical(y<0);
            y(g)=0;
            app.Deaths_index=y;
            
           
        end
        function Cumulative(app)
            Item_Selected=covid_data(app.CountryListBox.Value);
            [~, in2]=ismember(app.StateListBox.Value, app.StateListBox.Items);
            [~, in1]=ismember(app.CountryListBox.Value, app.CountryListBox.Items);
            if in1==1
                app.Cases_index=Item_Selected.global_cases;
                app.Deaths_index=Item_Selected.global_deaths;
            else
                app.Cases_index=Item_Selected.cases_index(Item_Selected.sindex(in2));
                app.Deaths_index=Item_Selected.death_index(Item_Selected.sindex(in2));
            end
            
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            c=covid_data('Global');
            app.CountryListBox.Items=unique(c.country,'stable');
            app.StateListBox.Items=unique(c.state,'stable');
            app.Cases_index=c.global_cases;
            app.Deaths_index=c.global_deaths;
            startingdate=datenum(c.date(1));
            lastdate=datenum(c.date(end));
            app.xData-linspace(startingdate,lastdate,length(c.date));
            app.AveragingWindow=app.AverageofdaysSlider.Value;
            app.StateListBoxValueChanged;
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            
            Item_Selected=covid_data(app.CountryListBox.Value);
            app.StateListBox.Items=unique(Item_Selected.states,'stable');
            app.StateListBoxValueChanged;
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            value = app.StateListBox.Value;
            [~, in2]=ismember(value,app.StateListBox.Items);
            if in2==1
                app.Int_title=app.CountryListBox.Value;
            else
                app.Int_title=app.CountryListBox.Value;
            end
            app.PlotData;
               
        end

        % Selection changed function: TypeofdataButtonGroup
        function TypeofdataButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofdataButtonGroup.SelectedObject;
            app.PlotData;
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            app.PlotData;
        end

        % Value changed function: AverageofdaysSlider
        function AverageofdaysSliderValueChanged(app, event)
            app.AveragingWindow=event.Value;
            if app.AveragingWindow==1
                tav1=int8('('); 
                tav2=int8('- day mean');
                app.tav=[tav1,int8(char(string(round(app.AveragingWindow)))),tav2];
            else
                app.tav=int8('');
            end
            app.PlotData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateListBoxLabel = uilabel(app.UIFigure);
            app.StateListBoxLabel.Text = 'State';
            app.StateListBox = uilistbox(app.UIFigure);
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.AverageofdaysSliderLabel = uilabel(app.UIFigure);
            app.AverageofdaysSliderLabel.Text = 'Average # of days';
            app.AverageofdaysSlider = uislider(app.UIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanged, true);
            app.AverageofdaysSlider.Value = 1;
            app.TypeofdataButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeofdataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofdataButtonGroupSelectionChanged, true);
            app.TypeofdataButtonGroup.Title = 'Type of data';
            app.CasesButton = uiradiobutton(app.TypeofdataButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.TypeofdataButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.TypeofdataButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end